classdef HEKA_Explorer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFIgure                    matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        OptionsPanel                  matlab.ui.container.Panel
        AppTitle                      matlab.ui.control.Label
        DataInformation               matlab.ui.control.EditField
        OptionsGroup                  matlab.ui.container.TabGroup
        DataOptionsTab                matlab.ui.container.Tab
        DataLoadingOptionsPanel       matlab.ui.container.Panel
        LoadDataButton                matlab.ui.control.Button
        DataFormatButtonGroup         matlab.ui.container.ButtonGroup
        LoadDATPatchMasterButton      matlab.ui.control.RadioButton
        LoadMATDataTableButton        matlab.ui.control.RadioButton
        LoadITXPatchMasterButton      matlab.ui.control.RadioButton
        LoadMATPatchMasterButton      matlab.ui.control.RadioButton
        LoadMATITXButton              matlab.ui.control.RadioButton
        DataConverstionPanel          matlab.ui.container.Panel
        ConvertITXtoMATButton         matlab.ui.control.Button
        ConvertDATtoMATButton         matlab.ui.control.Button
        DataLog                       matlab.ui.control.TextArea
        ViewOptionsTab                matlab.ui.container.Tab
        ViewProtocolAndSweepPanel     matlab.ui.container.Panel
        ViewProtocolSelectionLabel    matlab.ui.control.Label
        ViewProtocolSelection         matlab.ui.control.DropDown
        ViewProtocolNameChanger       matlab.ui.control.Button
        ViewPreviousProtocol          matlab.ui.control.Button
        ViewNextProtocol              matlab.ui.control.Button
        ViewSweepSelectionLabel       matlab.ui.control.Label
        ViewSweepSelection            matlab.ui.control.EditField
        ViewAllSweep                  matlab.ui.control.CheckBox
        VIewPreviousSweep             matlab.ui.control.Button
        ViewNextSweep                 matlab.ui.control.Button
        ViewPlotPropertiesPanel       matlab.ui.container.Panel
        VIewPlotNumberLabel           matlab.ui.control.Label
        ViewPlotNumber                matlab.ui.control.DropDown
        ViewPlotHeightRatioLabel      matlab.ui.control.Label
        ViewPlotHeightRatio           matlab.ui.control.EditField
        ViewVariableSelectionPanel    matlab.ui.container.Panel
        ViewVariableGrid              matlab.ui.container.GridLayout
        ViewVariable_1                matlab.ui.control.DropDown
        ViewVariable_2                matlab.ui.control.DropDown
        ViewVariable_3                matlab.ui.control.DropDown
        ViewVariable_4                matlab.ui.control.DropDown
        ViewVariable_5                matlab.ui.control.DropDown
        ViewVariable_6                matlab.ui.control.DropDown
        ViewVariable_7                matlab.ui.control.DropDown
        ViewVariable_8                matlab.ui.control.DropDown
        ViewVariable_9                matlab.ui.control.DropDown
        ViewVariablePropertiesPanel   matlab.ui.container.Panel
        ViewVariableOrignalNameLabel  matlab.ui.control.Label
        ViewVariableOrignalName       matlab.ui.control.DropDown
        ViewVariableNewNameLabel      matlab.ui.control.Label
        ViewVariableNewName           matlab.ui.control.EditField
        ViewVariableColorButton       matlab.ui.control.Button
        ViewVariableSaveButton        matlab.ui.control.StateButton
        ViewVariableYLimLabel         matlab.ui.control.Label
        ViewVariableYLimLowerBound    matlab.ui.control.NumericEditField
        ViewVariableYLimUpperBound    matlab.ui.control.NumericEditField
        ViewVariableYLimAuto          matlab.ui.control.CheckBox
        ViewXAxispropertiesPanel      matlab.ui.container.Panel
        VIewShowAllXAxis              matlab.ui.control.StateButton
        ViewVariableXLimLabel         matlab.ui.control.Label
        ViewVariableXLimLowerBound    matlab.ui.control.NumericEditField
        ViewVariableXLimUpperBound    matlab.ui.control.NumericEditField
        ViewVariableXLimAuto          matlab.ui.control.CheckBox
        ViewSaveOptionsPanel          matlab.ui.container.Panel
        ViewSaveSelectedOnly          matlab.ui.control.CheckBox
        ViewSaveDataFormat            matlab.ui.container.ButtonGroup
        ViewSaveMatButton             matlab.ui.control.RadioButton
        ViewSaveCSVButton             matlab.ui.control.RadioButton
        ViewSaveDataButton            matlab.ui.control.Button
        ViewScreenshotButton          matlab.ui.control.Button
        ViewLog                       matlab.ui.control.TextArea
        AnalysisOptionsTab            matlab.ui.container.Tab
        AnalysisLog                   matlab.ui.control.TextArea
        OutputsPanel                  matlab.ui.container.Panel
        OutputsGroup                  matlab.ui.container.TabGroup
        ViewOutputsTab                matlab.ui.container.Tab
        ViewFigureBoundary            matlab.ui.container.Panel
        AnalysisOutputsTab            matlab.ui.container.Tab
        AnalysisFigureBoundary        matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = public)
        % TODO: turn these into PRIVATE after development
        view_max_numplots = 9;          % maximum number of allowed plots in View
        current_data = struct;          % currently plotted data
        current_settings = struct;      % current settings/options
        current_variables = struct;     % current variables
        
        extopts = struct(...
            'DataAlreadyZeroed_inputs', ...
            {{'I know they ARE',...
            'I know they are NOT', ...
            'Unsure but let''s go with YES', ...
            'Unsure but let''s go with NO'}});
    end
    
    properties (Access = public)
        % TODO: decide on whether this should be publicly accessible or
        % turned to private
        data_table;                     % experiment data table
    end
    
    methods (Access = private)
        
        function [file_format, file_source] = getLoadDataFormat(app)
            selected_format = app.DataFormatButtonGroup.SelectedObject.Text;
            
            switch upper(selected_format)
                case upper('dat (from PatchMaster)')
                    file_format = 'dat';
                    file_source = 'PATCHMASTER::DAT';
                case upper('mat (data table)')
                    file_format = 'mat';
                    file_source = 'PATCHMASTER::DAT->HEKAEXPLORER::MAT';
                case upper('itx (from PatchMaster)')
                    file_format = 'itx';
                    file_source = 'PATCHMASTER::ITX';
                case upper('mat (from PatchMaster)')
                    file_format = 'mat';
                    file_source = 'PATCHMASTER::MAT';
                case upper('mat (from itx)')
                    file_format = 'mat';
                    file_source = 'PATCHMASTER::ITX->HEKAEXPLORER::MAT';
                otherwise
                    app.updateLog('DataLog', 'WARNING', ...
                        sprintf('%s is not an acceptable data format option. Nothing will be loaded.', selected_format));
                    file_format = 'INVALID';
                    file_source = 'INVALID';
            end
        end
        
        function updateLog(app, componentName, tag, newMessage)
            tag = upper(tag);
            app.(componentName).Value = [app.(componentName).Value; {sprintf('%s:\t%s', tag, newMessage)}];
            switch tag
                case {'WARNING', 'WARN'}
                    newMessage = [newMessage, 'Sorry, not sorry!'];
                    uialert(app.MainFIgure, newMessage, 'Icon', 'warning');
                case {'ERROR', 'ERR'}
                    newMessage = [newMessage, 'Actually, so sorry!'];
                    uialert(app.MainFIgure, newMessage, 'Icon', 'error');
            end
            
        end
        
        function initializeAfterLoadingData(app)
            
            selected_protocol_value = 1;
            app.current_settings.available_protocols = cellfun(@(ind_rec, stim_name) ...
                sprintf('%02d: %s',ind_rec,stim_name), ...
                num2cell(app.data_table.Rec), app.data_table.Stimulus, ...
                'uni', 0);
            app.current_settings.original_available_protocols = app.current_settings.available_protocols;
            
            % ProtocolSelection
            app.ViewProtocolSelection.Enable = 'on';
            app.ViewProtocolNameChanger.Enable = 'on';
            app.ViewNextProtocol.Enable = 'on';
            app.ViewPreviousProtocol.Enable = 'on';
            app.ViewProtocolSelection.Items = app.current_settings.available_protocols;
            app.ViewProtocolSelection.ItemsData = app.data_table.Rec;
            app.ViewProtocolSelection.Value = selected_protocol_value;
            
            
            % ViewPlotNumber
            app.ViewPlotNumber.Items = cellfun(@num2str, num2cell(1:app.view_max_numplots), 'uni', 0);
            app.ViewPlotNumber.ItemsData = 1:app.view_max_numplots;
            app.ViewPlotNumber.Enable = 'on';
            
            app.ViewProtocolSelectionChanged;
            
        end
        
        function switchToViewTabs(app)
            app.OptionsGroup.SelectedTab = app.ViewOptionsTab;
            app.OutputsGroup.SelectedTab = app.ViewOutputsTab;
        end
        
        function switchToAnalysTabs(app)
            app.OptionsGroup.SelectedTab = app.AnalysisOptionsTab;
            app.OutputsGroup.SelectedTab = app.AnalysisOutputsTab;
        end
        
        function DataAlreadyZeroed_answer = promptDataAlreadyZeroedHEKAPatchMasterImporter(app, full_filename)
            DataAlreadyZeroed_answer = listdlg('PromptString', ...
                {'Do you know if data have already been zeroed?', full_filename, '', ''}, ...
                'SelectionMode', 'single', ...
                'ListString', app.extopts.DataAlreadyZeroed_inputs, ...
                'ListSize', [300, 100]);
            if isempty(DataAlreadyZeroed_answer)
                DataAlreadyZeroed_answer = nan;
            else
                DataAlreadyZeroed_answer = DataAlreadyZeroed_answer == 1 || DataAlreadyZeroed_answer == 3;
            end
            
        end
        
        function dTbl = getHEKAImportedDataTable(app, full_filename)
            DataAlreadyZeroed_answer = app.promptDataAlreadyZeroedHEKAPatchMasterImporter(full_filename);
            
            if isnan(DataAlreadyZeroed_answer), dTbl = ''; return; end
            
            try
                tmp_data = HEKA_Importer(full_filename, 'DataAlreadyZeroed', DataAlreadyZeroed_answer);
            catch
                app.updateLog('DataLog', 'ERROR', sprintf('HEKA_Importer could not load %s.', full_filename));
            end
            
            if isempty(tmp_data.RecTable)
                app.updateLog('DataLog', 'WARNING', 'The data table was empty. Nothing will be loaded in.');
                return;
            end
            
            dTbl = tmp_data.RecTable;
        end
        
        function getRecordings(app, ind_rec)
            
            dt_ms = 1000/double(app.data_table.SR(ind_rec));
            results = struct('channels', struct(), 'stimulus', struct(), ...
                'dt_ms', dt_ms, 't', '', 'num_sweeps', double(app.data_table.nSweeps(ind_rec)));
            
            variable_names = struct;
            current_varind = 1;
            
            % Get monitored channel data
            channel_units = app.data_table.ChUnit{ind_rec};
            channel_names = app.data_table.ChName{ind_rec};
            channel_data  = app.data_table.dataRaw{ind_rec};
            
            num_channels = length(channel_data);
            for ind_chan = 1:num_channels
                chan_unit  = channel_units{ind_chan};
                chan_datum = channel_data{ind_chan};
                
                switch upper(chan_unit)
                    case 'A'
                        chan_unit  = 'nA';
                        chan_datum = chan_datum * 1e9;
                        chan_ylim = [-4, 0.5];
                    case 'V'
                        chan_unit  = 'mV';
                        chan_datum = chan_datum * 1e3;
                        chan_ylim = [-90, 60];
                    otherwise
                        error('%s is not an acceptable channel unit as this point', chan_unit);
                end
                
                channel_units{ind_chan} = chan_unit;
                channel_data{ind_chan} = chan_datum;
                
                variable_names.(sprintf('var%d', current_varind)) = struct(...
                    'original_name', channel_names{ind_chan}, ...
                    'new_name', channel_names{ind_chan}, ...
                    'identity', 'channels', ...
                    'index', ind_chan, ...
                    'unit', chan_unit, ...
                    'save', 1, ...
                    'color', 'k', ...
                    'axes_indices', [], ...
                    'ylim', chan_ylim ...
                    );
                current_varind = current_varind + 1;
            end
            
            results.channels.names = channel_names;
            results.channels.units = channel_units;
            results.channels.data  = channel_data;
            results.channels.num   = num_channels;
            
            % Get stimulus data
            stim_data = app.data_table.stimWave{ind_rec};
            stim_units = app.data_table.stimUnit{ind_rec};
            stim_names = fieldnames(stim_data);
            num_stims = length(stim_names);
            stim_units = cellfun(@(x) x(1), stim_units, 'uni', 0);
            
            for ind_stim = 1:num_stims
                variable_names.(sprintf('var%d', current_varind)) = struct(...
                    'original_name', stim_names{ind_stim}, ...
                    'new_name', stim_names{ind_stim}, ...
                    'identity', 'stimulus', ...
                    'index', ind_stim, ...
                    'unit', stim_units{ind_stim}, ...
                    'save', 1, ...
                    'color', 'r', ...
                    'axes_indices', [], ...
                    'ylim', 'auto' ...
                    );
                current_varind = current_varind + 1;
            end
            results.stimulus.names = stim_names;
            results.stimulus.units = stim_units;
            results.stimulus.data  = cellfun(@(x) stim_data.(x), stim_names, 'uni', 0);
            results.stimulus.num   = num_stims;
            
            % Time vector
            len_dat = size(channel_data{1},1);
            results.t = dt_ms*(0:(len_dat-1));
            
            % Update properties of apps to save these data
            app.current_data = results;
            app.current_variables = variable_names;
            
            app.current_settings.xlim = 'auto';
        end
        
        function results = showSelectedSweep(app)
            
        end
        
        function ViewAllSweepTickedInitialized(app)
            if app.ViewAllSweep.Value == 1
                sweep_selection_choice = 'off';
                if isfield(app.current_data, 'num_sweeps')
                    app.current_settings.sweeps.selected = 1:app.current_settings.sweeps.max;
                    app.ViewSweepSelection.Value = sprintf('1:%d', app.current_settings.sweeps.max);
                end
            else
                sweep_selection_choice = 'on';
                app.SweepSelectionInput.String = '1';
                app.current_settings.sweeps.selected = 1;
            end
            
            app.SweepSelectionInput.Enable = sweep_selection_choice;
            app.SweepSelectionNextButton.Enable = sweep_selection_choice;
            app.SweepSelectionPreviousButton.Enable = sweep_selection_choice;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % Prompt get file
            if ~isfield(app.current_settings, 'file_path'), app.current_settings.file_path = pwd; end
            file_path = app.current_settings.file_path;
            
            [file_format, file_source] = app.getLoadDataFormat;
            [file_name, file_path] = uigetfile(fullfile(file_path,['*.' file_format]), ...
                sprintf('Select a %s file to read', upper(file_format)), 'MultiSelect',"off");
            
            if file_name == 0, return; end
            
            app.current_settings.file_path = file_path;
            app.current_settings.file_name = file_name;
            
            % Update log
            app.updateLog('DataLog', 'DATA', 'wait for it ...');
            pause(0.001);
            
            % Obtain data table
            full_filename = fullfile(file_path, file_name);
            switch file_source
                case 'PATCHMASTER::DAT'
                    tmpdTbl = app.getHEKAImportedDataTable(full_filename);
                    if isempty(tmpdTbl), return; end
                    app.data_table = tmpdTbl;
                case 'PATCHMASTER::DAT->HEKAEXPLORER::MAT'
                    tmp_data = load(full_filename, 'data_table');
                    app.data_table = tmp_data.data_table;
                otherwise
                    app.updateLog('DataLog', 'WARNING', 'The file source option is either invalid or unimplemented for reading. Nothing will be loaded in.');
            end
            
            app.DataInformation.Value = file_name;
            app.updateLog('DataLog', 'DATA', sprintf('File "%s" loaded.', full_filename));
            app.updateLog('DataLog', 'TIPS', 'Now you can click on the other tabs to toy around.');
            
            % Initialize
            app.switchToViewTabs;
            app.initializeAfterLoadingData;
            
        end

        % Button pushed function: ConvertDATtoMATButton
        function ConvertDATtoMATButtonPushed(app, event)
            sel_opts = {'Save the loaded DAT as MAT','Convert other DAT files','Cancel'};
            selection = uiconfirm(app.MainFIgure,'Do you want to convert the loaded data?', 'Convert DAT to MAT',...
                'Options', sel_opts, 'DefaultOption',1,'CancelOption',3);
            
            
            switch selection
                case sel_opts{1}
                    if isempty(app.data_table)
                        app.updateLog('DataLog', 'WARNING', 'Empty data table. Please load the data again. Nothing will be saved.');
                        return;
                    end
                    
                    [save_datatable_name, save_datatable_path] = uiputfile('.mat', ...
                        'Select a home for the MAT data table file', ...
                        sprintf('%s.mat', fullfile(app.current_settings.file_path, app.current_settings.file_name(1:end-4))));
                    
                    if save_datatable_name == 0, return; end
                    save_filename = fullfile(save_datatable_path, save_datatable_name);
                    data_table = app.data_table; %#ok<ADPROPLC>
                    save(save_filename, 'data_table');
                    app.updateLog('DataLog', 'DATA', sprintf('"%s" was saved at %s', save_datatable_name, save_datatable_path));
                case sel_opts{2}
                    if ~isfield(app.current_settings, 'file_path')
                        file_path = pwd;
                    else
                        file_path = app.current_settings.file_path;
                    end
                    
                    [file_names, file_path] = uigetfile(fullfile(file_path,'*.dat'), ...
                        sprintf('Select a %s file to read', 'MAT'), 'MultiSelect',"on");
                    if isnumeric(file_path), return; end
                    if ischar(file_names), file_names = {file_names}; end
                    for ind_file = 1:length(file_names)
                        full_filename = fullfile(file_path, file_names{ind_file});
                        data_table = app.getHEKAImportedDataTable(full_filename);  %#ok<ADPROPLC>
                        if isempty(data_table)  %#ok<ADPROPLC>
                            app.updateLog('DataLog', 'WARNING', 'You missed an important selections along the way. Nothing converted.')
                            return;
                        end
                        
                        [save_datatable_name, save_datatable_path] = uiputfile('.mat', ...
                            'Select a home for the MAT data table file', ...
                            sprintf('%s.mat', full_filename(1:end-4)));
                        
                        if save_datatable_name == 0, return; end
                        save_filename = fullfile(save_datatable_path, save_datatable_name);
                        save(save_filename, 'data_table');
                        app.updateLog('DataLog', 'DATA', sprintf('"%s" was saved at %s', save_datatable_name, save_datatable_path));
                    end
                case sel_opts{3}
                    return;
            end
            
        end

        % Button pushed function: ConvertITXtoMATButton
        function ConvertITXtoMATButtonPushed(app, event)
            app.updateLog('DataLog', 'WARNING', ['Nothing will be printed here for ITX->MAT conversion information. ' ...
                'Direct your attention to the command window. Sorry, I''m too lazy to integrate it in']);
            itx2mat;
            app.updateLog('DataLog', 'DATA', ['Seems like it''s done. Hope it was successful. ' ...
                'Again, go to the command window for more info']);
        end

        % Value changed function: ViewProtocolSelection
        function ViewProtocolSelectionChanged(app, event)
            % Get recordings from the selected protocol
            selected_protocol_value = app.ViewProtocolSelection.Value;
            
            app.current_settings.protocol.value = selected_protocol_value;
            app.current_settings.protocol.name = app.current_settings.available_protocols{selected_protocol_value};
            
            app.getRecordings(selected_protocol_value);
            num_sweeps = app.current_data.num_sweeps;
            app.current_settings.sweeps.max = num_sweeps;
            
            % ViewSweepSelection
            app.ViewAllSweep.Enable = 'on';
            app.ViewAllSweep.Value = 1;
            app.ViewAllSweepTickedInitialized;
            app.ViewSweepSelection.Enable = 'on';
            
            var_names = struct2cell(structfun(@(x) x.original_name, app.current_variables, 'uni', 0));
            app.NumberOfVariableMenu.Value = min(app.max_numplots, length(var_names));
            
            selected_varinds = (1:app.max_numplots)';
            selected_varinds(selected_varinds > length(var_names)) = 1;
            arrayfun(@(x,v) set(x, 'String', var_names, 'Value', v), ...
                app.VariableSelection_MenuList, selected_varinds);
            
            app.NumberOfVariableMenuChanged;
            
            app.VariableManipulation_OriginalNameMenu.String = var_names;
            app.VariableManipulation_OriginalNameMenu.Value = 1;
            app.VariableManipulation_OriginalNameMenuChanged;
            
            app.XAxisLinkButton.Value = 1;
            app.XAxisShowAllButton.Value = 0;
            
            app.plotTraces;
        end

        % Value changed function: ViewAllSweep
        function ViewAllSweepTicked(app, event)
            app.ViewAllSweepTickedInitialized;
            app.showOnlySelectedSweep;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MainFIgure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {866, 866};
                app.GridLayout.ColumnWidth = {'1x'};
                app.OutputsPanel.Layout.Row = 2;
                app.OutputsPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {370, '1x'};
                app.OutputsPanel.Layout.Row = 1;
                app.OutputsPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainFIgure = uifigure('Visible', 'off');
            app.MainFIgure.Name = 'UI Figure';
            app.MainFIgure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.MainFIgure);
            app.OptionsPanel = uipanel(app.GridLayout);
            app.AppTitle = uilabel(app.OptionsPanel);
            app.AppTitle.Text = 'PatchMaster recordings explorer';
            app.DataInformation = uieditfield(app.OptionsPanel, 'text');
            app.DataInformation.Enable = 'off';
            app.DataInformation.Value = '(no data loaded yet)';
            app.OptionsGroup = uitabgroup(app.OptionsPanel);
            app.DataOptionsTab = uitab(app.OptionsGroup);
            app.DataOptionsTab.Title = 'Data';
            app.DataLoadingOptionsPanel = uipanel(app.DataOptionsTab);
            app.LoadDataButton = uibutton(app.DataLoadingOptionsPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Tooltip = {'Click here and choose the file format to load the data'};
            app.LoadDataButton.Text = 'Load data';
            app.DataFormatButtonGroup = uibuttongroup(app.DataLoadingOptionsPanel);
            app.DataFormatButtonGroup.Tooltip = {'choose data format (the greyed ones are unimplmented)'; ''};
            app.DataFormatButtonGroup.Title = 'Data format';
            app.LoadDATPatchMasterButton = uiradiobutton(app.DataFormatButtonGroup);
            app.LoadDATPatchMasterButton.Tooltip = {'.dat files generated for the entire experiment from PatchMaster'};
            app.LoadDATPatchMasterButton.Text = 'dat (from PatchMaster)';
            app.LoadDATPatchMasterButton.Value = true;
            app.LoadMATDataTableButton = uiradiobutton(app.DataFormatButtonGroup);
            app.LoadMATDataTableButton.Tooltip = {'.mat files generated by this program that had the data table, extracted from the HEKA_Importer'};
            app.LoadMATDataTableButton.Text = 'mat (data table)';
            app.LoadITXPatchMasterButton = uiradiobutton(app.DataFormatButtonGroup);
            app.LoadITXPatchMasterButton.Enable = 'off';
            app.LoadITXPatchMasterButton.Tooltip = {'.itx files from PatchMaster for single protocol'; ''};
            app.LoadITXPatchMasterButton.Text = 'itx (from PatchMaster)';
            app.LoadMATPatchMasterButton = uiradiobutton(app.DataFormatButtonGroup);
            app.LoadMATPatchMasterButton.Enable = 'off';
            app.LoadMATPatchMasterButton.Tooltip = {'.mat files from PatchMaster for single protocol'};
            app.LoadMATPatchMasterButton.Text = 'mat (from PatchMaster)';
            app.LoadMATITXButton = uiradiobutton(app.DataFormatButtonGroup);
            app.LoadMATITXButton.Enable = 'off';
            app.LoadMATITXButton.Tooltip = {'.mat files converted from .itx files'};
            app.LoadMATITXButton.Text = 'mat (from itx)';
            app.DataConverstionPanel = uipanel(app.DataOptionsTab);
            app.DataConverstionPanel.Title = 'Conversion utilities';
            app.ConvertITXtoMATButton = uibutton(app.DataConverstionPanel, 'push');
            app.ConvertITXtoMATButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertITXtoMATButtonPushed, true);
            app.ConvertITXtoMATButton.Tooltip = {'Convert the .itx files to.mat files for easy reading. Can do batch mode as well.'};
            app.ConvertITXtoMATButton.Text = 'Convert ITX to MAT';
            app.ConvertDATtoMATButton = uibutton(app.DataConverstionPanel, 'push');
            app.ConvertDATtoMATButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertDATtoMATButtonPushed, true);
            app.ConvertDATtoMATButton.Tooltip = {'Convert the PatchMaster .dat files to.mat files for easy reading (essentially from HEKA Importer). Can do batch mode as well.'};
            app.ConvertDATtoMATButton.Text = 'Convert DAT to MAT';
            app.DataLog = uitextarea(app.DataOptionsTab);
            app.DataLog.Enable = 'off';
            app.DataLog.Value = {'Log'};
            app.ViewOptionsTab = uitab(app.OptionsGroup);
            app.ViewOptionsTab.Title = 'View options';
            app.ViewProtocolAndSweepPanel = uipanel(app.ViewOptionsTab);
            app.ViewProtocolAndSweepPanel.Title = 'Protocol and sweep selection';
            app.ViewProtocolSelectionLabel = uilabel(app.ViewProtocolAndSweepPanel);
            app.ViewProtocolSelectionLabel.Text = 'Protocol';
            app.ViewProtocolSelection = uidropdown(app.ViewProtocolAndSweepPanel);
            app.ViewProtocolSelection.Items = {};
            app.ViewProtocolSelection.ValueChangedFcn = createCallbackFcn(app, @ViewProtocolSelectionChanged, true);
            app.ViewProtocolSelection.Enable = 'off';
            app.ViewProtocolSelection.Tooltip = {'Select a protocol'};
            app.ViewProtocolSelection.Value = {};
            app.ViewProtocolNameChanger = uibutton(app.ViewProtocolAndSweepPanel, 'push');
            app.ViewProtocolNameChanger.Enable = 'off';
            app.ViewProtocolNameChanger.Tooltip = {'Click here to change the name of the protocol'};
            app.ViewProtocolNameChanger.Text = 'C';
            app.ViewPreviousProtocol = uibutton(app.ViewProtocolAndSweepPanel, 'push');
            app.ViewPreviousProtocol.Enable = 'off';
            app.ViewPreviousProtocol.Tooltip = {'Previous protocol'};
            app.ViewPreviousProtocol.Text = '◀';
            app.ViewNextProtocol = uibutton(app.ViewProtocolAndSweepPanel, 'push');
            app.ViewNextProtocol.Enable = 'off';
            app.ViewNextProtocol.Tooltip = {'Next protocol'};
            app.ViewNextProtocol.Text = '▶';
            app.ViewSweepSelectionLabel = uilabel(app.ViewProtocolAndSweepPanel);
            app.ViewSweepSelectionLabel.Text = 'Sweep';
            app.ViewSweepSelection = uieditfield(app.ViewProtocolAndSweepPanel, 'text');
            app.ViewSweepSelection.Enable = 'off';
            app.ViewAllSweep = uicheckbox(app.ViewProtocolAndSweepPanel);
            app.ViewAllSweep.ValueChangedFcn = createCallbackFcn(app, @ViewAllSweepTicked, true);
            app.ViewAllSweep.Enable = 'off';
            app.ViewAllSweep.Tooltip = {'Click all to plot all weeps '};
            app.ViewAllSweep.Text = ' all';
            app.VIewPreviousSweep = uibutton(app.ViewProtocolAndSweepPanel, 'push');
            app.VIewPreviousSweep.Enable = 'off';
            app.VIewPreviousSweep.Tooltip = {'Previous protocol'};
            app.VIewPreviousSweep.Text = '◀';
            app.ViewNextSweep = uibutton(app.ViewProtocolAndSweepPanel, 'push');
            app.ViewNextSweep.Enable = 'off';
            app.ViewNextSweep.Tooltip = {'Next protocol'};
            app.ViewNextSweep.Text = '▶';
            app.ViewPlotPropertiesPanel = uipanel(app.ViewOptionsTab);
            app.ViewPlotPropertiesPanel.Title = 'Plot properties';
            app.VIewPlotNumberLabel = uilabel(app.ViewPlotPropertiesPanel);
            app.VIewPlotNumberLabel.Text = 'Nunber';
            app.ViewPlotNumber = uidropdown(app.ViewPlotPropertiesPanel);
            app.ViewPlotNumber.Items = {};
            app.ViewPlotNumber.Enable = 'off';
            app.ViewPlotNumber.Tooltip = {'maximum 9 plots'};
            app.ViewPlotNumber.Value = {};
            app.ViewPlotHeightRatioLabel = uilabel(app.ViewPlotPropertiesPanel);
            app.ViewPlotHeightRatioLabel.Text = 'Height ratio';
            app.ViewPlotHeightRatio = uieditfield(app.ViewPlotPropertiesPanel, 'text');
            app.ViewPlotHeightRatio.Enable = 'off';
            app.ViewVariableSelectionPanel = uipanel(app.ViewOptionsTab);
            app.ViewVariableSelectionPanel.Title = 'Variable selections for plotting';
            app.ViewVariableGrid = uigridlayout(app.ViewVariableSelectionPanel);
            app.ViewVariable_1 = uidropdown(app.ViewVariableGrid);
            app.ViewVariable_1.Items = {};
            app.ViewVariable_1.Enable = 'off';
            app.ViewVariable_1.Tooltip = {'Select a variable to plot'};
            app.ViewVariable_1.Value = {};
            app.ViewVariable_2 = uidropdown(app.ViewVariableGrid);
            app.ViewVariable_2.Items = {};
            app.ViewVariable_2.Enable = 'off';
            app.ViewVariable_2.Tooltip = {'Select a variable to plot'};
            app.ViewVariable_2.Value = {};
            app.ViewVariable_3 = uidropdown(app.ViewVariableGrid);
            app.ViewVariable_3.Items = {};
            app.ViewVariable_3.Enable = 'off';
            app.ViewVariable_3.Tooltip = {'Select a variable to plot'};
            app.ViewVariable_3.Value = {};
            app.ViewVariable_4 = uidropdown(app.ViewVariableGrid);
            app.ViewVariable_4.Items = {};
            app.ViewVariable_4.Enable = 'off';
            app.ViewVariable_4.Tooltip = {'Select a variable to plot'};
            app.ViewVariable_4.Value = {};
            app.ViewVariable_5 = uidropdown(app.ViewVariableGrid);
            app.ViewVariable_5.Items = {};
            app.ViewVariable_5.Enable = 'off';
            app.ViewVariable_5.Tooltip = {'Select a variable to plot'};
            app.ViewVariable_5.Value = {};
            app.ViewVariable_6 = uidropdown(app.ViewVariableGrid);
            app.ViewVariable_6.Items = {};
            app.ViewVariable_6.Enable = 'off';
            app.ViewVariable_6.Tooltip = {'Select a variable to plot'};
            app.ViewVariable_6.Value = {};
            app.ViewVariable_7 = uidropdown(app.ViewVariableGrid);
            app.ViewVariable_7.Items = {};
            app.ViewVariable_7.Enable = 'off';
            app.ViewVariable_7.Tooltip = {'Select a variable to plot'};
            app.ViewVariable_7.Value = {};
            app.ViewVariable_8 = uidropdown(app.ViewVariableGrid);
            app.ViewVariable_8.Items = {};
            app.ViewVariable_8.Enable = 'off';
            app.ViewVariable_8.Tooltip = {'Select a variable to plot'};
            app.ViewVariable_8.Value = {};
            app.ViewVariable_9 = uidropdown(app.ViewVariableGrid);
            app.ViewVariable_9.Items = {};
            app.ViewVariable_9.Enable = 'off';
            app.ViewVariable_9.Tooltip = {'Select a variable to plot'};
            app.ViewVariable_9.Value = {};
            app.ViewVariablePropertiesPanel = uipanel(app.ViewOptionsTab);
            app.ViewVariablePropertiesPanel.Title = 'Variable properties';
            app.ViewVariableOrignalNameLabel = uilabel(app.ViewVariablePropertiesPanel);
            app.ViewVariableOrignalNameLabel.Text = 'Original name';
            app.ViewVariableOrignalName = uidropdown(app.ViewVariablePropertiesPanel);
            app.ViewVariableOrignalName.Items = {};
            app.ViewVariableOrignalName.Enable = 'off';
            app.ViewVariableOrignalName.Value = {};
            app.ViewVariableNewNameLabel = uilabel(app.ViewVariablePropertiesPanel);
            app.ViewVariableNewNameLabel.Text = 'New name';
            app.ViewVariableNewName = uieditfield(app.ViewVariablePropertiesPanel, 'text');
            app.ViewVariableNewName.Enable = 'off';
            app.ViewVariableColorButton = uibutton(app.ViewVariablePropertiesPanel, 'push');
            app.ViewVariableColorButton.Enable = 'off';
            app.ViewVariableColorButton.Text = 'Color';
            app.ViewVariableSaveButton = uibutton(app.ViewVariablePropertiesPanel, 'state');
            app.ViewVariableSaveButton.Enable = 'off';
            app.ViewVariableSaveButton.Text = 'Save';
            app.ViewVariableYLimLabel = uilabel(app.ViewVariablePropertiesPanel);
            app.ViewVariableYLimLabel.Text = 'YLim';
            app.ViewVariableYLimLowerBound = uieditfield(app.ViewVariablePropertiesPanel, 'numeric');
            app.ViewVariableYLimLowerBound.Enable = 'off';
            app.ViewVariableYLimUpperBound = uieditfield(app.ViewVariablePropertiesPanel, 'numeric');
            app.ViewVariableYLimUpperBound.Enable = 'off';
            app.ViewVariableYLimAuto = uicheckbox(app.ViewVariablePropertiesPanel);
            app.ViewVariableYLimAuto.Enable = 'off';
            app.ViewVariableYLimAuto.Text = 'auto';
            app.ViewVariableYLimAuto.Value = true;
            app.ViewXAxispropertiesPanel = uipanel(app.ViewOptionsTab);
            app.ViewXAxispropertiesPanel.Title = 'X-axis properties';
            app.VIewShowAllXAxis = uibutton(app.ViewXAxispropertiesPanel, 'state');
            app.VIewShowAllXAxis.Enable = 'off';
            app.VIewShowAllXAxis.Text = 'Show all X-axis';
            app.VIewShowAllXAxis.Value = true;
            app.ViewVariableXLimLabel = uilabel(app.ViewXAxispropertiesPanel);
            app.ViewVariableXLimLabel.Text = 'XLims';
            app.ViewVariableXLimLowerBound = uieditfield(app.ViewXAxispropertiesPanel, 'numeric');
            app.ViewVariableXLimLowerBound.Enable = 'off';
            app.ViewVariableXLimUpperBound = uieditfield(app.ViewXAxispropertiesPanel, 'numeric');
            app.ViewVariableXLimUpperBound.Enable = 'off';
            app.ViewVariableXLimAuto = uicheckbox(app.ViewXAxispropertiesPanel);
            app.ViewVariableXLimAuto.Enable = 'off';
            app.ViewVariableXLimAuto.Text = 'auto';
            app.ViewVariableXLimAuto.Value = true;
            app.ViewSaveOptionsPanel = uipanel(app.ViewOptionsTab);
            app.ViewSaveOptionsPanel.Title = 'Save options';
            app.ViewSaveSelectedOnly = uicheckbox(app.ViewSaveOptionsPanel);
            app.ViewSaveSelectedOnly.Enable = 'off';
            app.ViewSaveSelectedOnly.Text = 'selected only';
            app.ViewSaveDataFormat = uibuttongroup(app.ViewSaveOptionsPanel);
            app.ViewSaveMatButton = uiradiobutton(app.ViewSaveDataFormat);
            app.ViewSaveMatButton.Text = 'mat';
            app.ViewSaveMatButton.Value = true;
            app.ViewSaveCSVButton = uiradiobutton(app.ViewSaveDataFormat);
            app.ViewSaveCSVButton.Enable = 'off';
            app.ViewSaveCSVButton.Text = 'csv';
            app.ViewSaveDataButton = uibutton(app.ViewSaveOptionsPanel, 'push');
            app.ViewSaveDataButton.Enable = 'off';
            app.ViewSaveDataButton.Text = 'Save data';
            app.ViewScreenshotButton = uibutton(app.ViewSaveOptionsPanel, 'push');
            app.ViewScreenshotButton.Enable = 'off';
            app.ViewScreenshotButton.Text = 'Take screenshot';
            app.ViewLog = uitextarea(app.ViewOptionsTab);
            app.ViewLog.Enable = 'off';
            app.ViewLog.Value = {'Log'};
            app.AnalysisOptionsTab = uitab(app.OptionsGroup);
            app.AnalysisOptionsTab.Title = 'Analysis options';
            app.AnalysisLog = uitextarea(app.AnalysisOptionsTab);
            app.AnalysisLog.Enable = 'off';
            app.AnalysisLog.Value = {'Log'};
            app.OutputsPanel = uipanel(app.GridLayout);
            app.OutputsGroup = uitabgroup(app.OutputsPanel);
            app.ViewOutputsTab = uitab(app.OutputsGroup);
            app.ViewOutputsTab.Title = 'View output';
            app.ViewFigureBoundary = uipanel(app.ViewOutputsTab);
            app.AnalysisOutputsTab = uitab(app.OutputsGroup);
            app.AnalysisOutputsTab.Title = 'Analysis output';
            app.AnalysisFigureBoundary = uipanel(app.AnalysisOutputsTab);
            % Show the figure after all components are created
            app.MainFIgure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HEKA_Explorer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainFIgure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFIgure)
        end
    end
end